classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        RenderButton           matlab.ui.control.Button
        UIAxes                 matlab.ui.control.UIAxes
        SetupPanel             matlab.ui.container.Panel
        SetuppathtracerButton  matlab.ui.control.Button
        xresLabel              matlab.ui.control.Label
        xresEditField          matlab.ui.control.NumericEditField
        yresLabel              matlab.ui.control.Label
        yresEditField          matlab.ui.control.NumericEditField
        MeshnameListBoxLabel   matlab.ui.control.Label
        MeshnameListBox        matlab.ui.control.ListBox
        SaveimageButton        matlab.ui.control.Button
        RendertimeLabel        matlab.ui.control.Label
    end


    properties (Access = private)
        mPathTracer@PathTracer % Description
        mSetupComplete@logical = false
    end


    methods (Access = private)

        % Button pushed function: RenderButton
        function RenderButtonPushed(app, event)
            if (app.mSetupComplete)
                renderTime = app.mPathTracer.render(app);
                app.RendertimeLabel.Text = strcat('Render time:: ', num2str(renderTime*1000), ' ms');
            else
                error("Setup is not complete!");
            end
        end

        % Button pushed function: SetuppathtracerButton
        function SetuppathtracerButtonPushed(app, event)
            x = app.xresEditField.Value;
            y = app.yresEditField.Value;
            meshName = app.MeshnameListBox.Value;
            app.mPathTracer = PathTracer(x,y, meshName);
            app.mSetupComplete = true;
            app.RenderButton.Enable = 'on';
            app.SaveimageButton.Enable = 'on';
            app.SetuppathtracerButton.BackgroundColor = [0 1 0];
        end

        % Button pushed function: SaveimageButton
        function SaveimageButtonPushed(app, event)
            if (app.mSetupComplete)
                app.mPathTracer.writeOutputToFile();
            else
                error("Setup is not complete!");
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.RenderButton = uibutton(app.UIFigure, 'push');
            app.RenderButton.ButtonPushedFcn = createCallbackFcn(app, @RenderButtonPushed, true);
            app.RenderButton.Enable = 'off';
            app.RenderButton.Text = 'Render';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Output')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.SetupPanel = uipanel(app.UIFigure);
            app.SetupPanel.Title = 'Setup';
            app.SetuppathtracerButton = uibutton(app.SetupPanel, 'push');
            app.SetuppathtracerButton.ButtonPushedFcn = createCallbackFcn(app, @SetuppathtracerButtonPushed, true);
            app.SetuppathtracerButton.Text = 'Set up path tracer';
            app.xresLabel = uilabel(app.SetupPanel);
            app.xresLabel.Text = 'x res';
            app.xresEditField = uieditfield(app.SetupPanel, 'numeric');
            app.xresEditField.UpperLimitInclusive = 'off';
            app.xresEditField.Limits = [1 Inf];
            app.xresEditField.RoundFractionalValues = 'on';
            app.xresEditField.ValueDisplayFormat = '%.0f';
            app.xresEditField.Value = 1;
            app.yresLabel = uilabel(app.SetupPanel);
            app.yresLabel.Text = 'y res';
            app.yresEditField = uieditfield(app.SetupPanel, 'numeric');
            app.yresEditField.UpperLimitInclusive = 'off';
            app.yresEditField.Limits = [1 Inf];
            app.yresEditField.RoundFractionalValues = 'on';
            app.yresEditField.ValueDisplayFormat = '%.0f';
            app.yresEditField.Value = 1;
            app.MeshnameListBoxLabel = uilabel(app.SetupPanel);
            app.MeshnameListBoxLabel.Text = 'Mesh name';
            app.MeshnameListBox = uilistbox(app.SetupPanel);
            app.MeshnameListBox.Items = {'Single Triangle', 'Sphere', 'null'};
            app.MeshnameListBox.Value = 'Single Triangle';
            app.SaveimageButton = uibutton(app.UIFigure, 'push');
            app.SaveimageButton.ButtonPushedFcn = createCallbackFcn(app, @SaveimageButtonPushed, true);
            app.SaveimageButton.Enable = 'off';
            app.SaveimageButton.Text = 'Save image';
            app.RendertimeLabel = uilabel(app.UIFigure);
            app.RendertimeLabel.Text = 'Render time:';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end